classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        resetButton        matlab.ui.control.Button
        test               matlab.ui.control.Label
        kHzSlider_5Label   matlab.ui.control.Label
        kHzSlider_5        matlab.ui.control.Slider
        kHzSlider_4Label   matlab.ui.control.Label
        kHzSlider_4        matlab.ui.control.Slider
        kHzSlider_3Label   matlab.ui.control.Label
        kHzSlider_3        matlab.ui.control.Slider
        kHzSlider_2Label   matlab.ui.control.Label
        kHzSlider_2        matlab.ui.control.Slider
        kHzSliderLabel     matlab.ui.control.Label
        kHzSlider          matlab.ui.control.Slider
        HzSlider_5Label    matlab.ui.control.Label
        HzSlider_5         matlab.ui.control.Slider
        HzSlider_4Label    matlab.ui.control.Label
        HzSlider_4         matlab.ui.control.Slider
        HzSlider_3Label    matlab.ui.control.Label
        HzSlider_3         matlab.ui.control.Slider
        HzSlider_2Label    matlab.ui.control.Label
        HzSlider_2         matlab.ui.control.Slider
        Millisecdigit0     matlab.ui.control.Label
        Millisecdigit1     matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        Seconddigit0       matlab.ui.control.Label
        Minutedigit1       matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Seconddigit1       matlab.ui.control.Label
        Minutedigit0       matlab.ui.control.Label
        HzSlider           matlab.ui.control.Slider
        HzSliderLabel      matlab.ui.control.Label
        Label              matlab.ui.control.Label
        PathLabel          matlab.ui.control.Label
        stopButton         matlab.ui.control.Button
        BrowsefilesButton  matlab.ui.control.Button
        ResumeButton       matlab.ui.control.Button
        PlayButton         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        ContextMenu        matlab.ui.container.ContextMenu
        Menu               matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
        Menu2              matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Samplerate = NaN;
        Audiodata = NaN;
        player = NaN;
        State_pause = 1;
        equalizer = graphicEQ("Gains",[0,0,0,0,0,0,0,0,0,0])
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
                
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            try
                stop(app.player);
                play(app.player);
                app.State_pause = 0;
                app.ResumeButton.Text = "Pause";
            catch
                [y,Fs] = audioread("error sound.mp3");
                app.Samplerate = Fs;
                app.Audiodata = y;
                app.player = audioplayer(app.Audiodata ,app.Samplerate);
                stop(app.player);
                play(app.player);
            end
            
        end

        % Button pushed function: BrowsefilesButton
        function BrowsefilesButtonPushed(app, event)
            [filename,path] = uigetfile({'*.m4a;*.mp4;*.mp3;*.wav;*.opus;*.ogg;*.flac;*.au;*.aiff;*.aif;*.aifc'},'Select One Files');
            fullpath = [path filename];           
            try 
                fullpath == [0 0]
                app.Label.Text = "";
                [y,Fs] = audioread("error sound.mp3");
                app.Samplerate = Fs;
                app.Audiodata = y;
                app.player = audioplayer(app.Audiodata ,app.Samplerate);
            catch           
                app.Label.Text = fullpath;
                [y,Fs] = audioread(fullpath);
                app.Samplerate = Fs;
                app.Audiodata = y;
                app.HzSlider.Value = 0;
                app.HzSlider_2.Value = 0;
                app.HzSlider_3.Value = 0;
                app.HzSlider_4.Value = 0;
                app.HzSlider_5.Value = 0;
                app.kHzSlider.Value = 0;
                app.kHzSlider_2.Value = 0;
                app.kHzSlider_3.Value = 0;
                app.kHzSlider_4.Value = 0;
                app.kHzSlider_5.Value = 0;
                app.equalizer.Gains = [0,0,0,0,0,0,0,0,0,0];
                app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
                plot(app.UIAxes,app.Audiodata);
                Times = length(app.Audiodata)/app.Samplerate;
                min = floor(Times/60);
                sec = mod(Times,60);
                millisec = mod(sec,floor(sec))*100;
                app.Minutedigit1.Text = string(floor(min/10));
                app.Minutedigit0.Text = string(mod(min,10));

                app.Seconddigit1.Text = string(floor(sec/10));
                app.Seconddigit0.Text = string(floor(mod(sec,10)));

                app.Millisecdigit1.Text = string(floor(millisec/10));
                app.Millisecdigit0.Text = string(floor(mod(millisec,10)));
                
            end
            app.State_pause = 1;
            app.ResumeButton.Text = "Resume";
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
              if app.State_pause == 0
                  pause(app.player);
                  app.State_pause = 1;
                  app.ResumeButton.Text = "Resume";
              else
                  try
                    resume(app.player);
                    app.State_pause = 0;
                    app.ResumeButton.Text = "Pause";
                  catch
                    [y,Fs] = audioread("error sound.mp3");
                    app.Samplerate = Fs;
                    app.Audiodata = y;
                    app.player = audioplayer(app.Audiodata ,app.Samplerate);
                    resume(app.player);
                  end
              end
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            try
                stop(app.player);
                app.State_pause = 1;
                app.ResumeButton.Text = "Resume";
            catch
                [y,Fs] = audioread("error sound.mp3");
                app.Samplerate = Fs;
                app.Audiodata = y;
                app.player = audioplayer(app.Audiodata ,app.Samplerate);
                play(app.player);
            end
        end

        % Value changing function: HzSlider
        function HzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(1) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Value changed function: HzSlider
        function HzSliderValueChanged(app, event)
            value = app.HzSlider.Value;
%             app.equalizer.Gains(1) = value; 
        end

        % Value changed function: HzSlider_2
        function HzSlider_2ValueChanged(app, event)
            value = app.HzSlider_2.Value;
%             app.equalizer.Gains(2) = value; 
        end

        % Value changed function: HzSlider_3
        function HzSlider_3ValueChanged(app, event)
            value = app.HzSlider_3.Value;
%             app.equalizer.Gains(3) = value; 
        end

        % Value changed function: HzSlider_4
        function HzSlider_4ValueChanged(app, event)
            value = app.HzSlider_4.Value;
%             app.equalizer.Gains(4) = value; 
        end

        % Value changed function: HzSlider_5
        function HzSlider_5ValueChanged(app, event)
            value = app.HzSlider_5.Value;
%             app.equalizer.Gains(5) = value; 
        end

        % Value changed function: kHzSlider
        function kHzSliderValueChanged(app, event)
            value = app.kHzSlider.Value;
%             app.equalizer.Gains(6) = value; 
        end

        % Value changed function: kHzSlider_2
        function kHzSlider_2ValueChanged(app, event)
            value = app.kHzSlider_2.Value;
%             app.equalizer.Gains(7) = value; 
        end

        % Value changed function: kHzSlider_3
        function kHzSlider_3ValueChanged(app, event)
            value = app.kHzSlider_3.Value;
%             app.equalizer.Gains(8) = value; 
        end

        % Value changed function: kHzSlider_4
        function kHzSlider_4ValueChanged(app, event)
            value = app.kHzSlider_4.Value;
%             app.equalizer.Gains(9) = value; 
        end

        % Value changed function: kHzSlider_5
        function kHzSlider_5ValueChanged(app, event)
            value = app.kHzSlider_5.Value;
%             app.equalizer.Gains(10) = value; 
        end

        % Value changing function: HzSlider_2
        function HzSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(2) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Value changing function: HzSlider_3
        function HzSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(3) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Value changing function: HzSlider_4
        function HzSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(4) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Value changing function: HzSlider_5
        function HzSlider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(5) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Value changing function: kHzSlider
        function kHzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(6) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Value changing function: kHzSlider_2
        function kHzSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(7) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Value changing function: kHzSlider_3
        function kHzSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(8) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Value changing function: kHzSlider_4
        function kHzSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(9) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Value changing function: kHzSlider_5
        function kHzSlider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.equalizer.Gains(10) = changingValue;
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.HzSlider.Value = 0;
            app.HzSlider_2.Value = 0;
            app.HzSlider_3.Value = 0;
            app.HzSlider_4.Value = 0;
            app.HzSlider_5.Value = 0;
            app.kHzSlider.Value = 0;
            app.kHzSlider_2.Value = 0;
            app.kHzSlider_3.Value = 0;
            app.kHzSlider_4.Value = 0;
            app.kHzSlider_5.Value = 0;
            app.equalizer.Gains = [0,0,0,0,0,0,0,0,0,0];
            app.player = audioplayer(app.equalizer(app.Audiodata) ,app.Samplerate);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.BrowsefilesButton = uibutton(app.UIFigure, 'push');
            app.BrowsefilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefilesButtonPushed, true);
            app.BrowsefilesButton.Text = 'Browse files';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.HzSliderLabel = uilabel(app.UIFigure);
            app.HzSliderLabel.Text = '31.5 Hz';
            app.HzSlider = uislider(app.UIFigure);
            app.HzSlider.Limits = [-15 15];
            app.HzSlider.ValueChangedFcn = createCallbackFcn(app, @HzSliderValueChanged, true);
            app.HzSlider.ValueChangingFcn = createCallbackFcn(app, @HzSliderValueChanging, true);
            app.Minutedigit0 = uilabel(app.UIFigure);
            app.Minutedigit0.Text = '0';
            app.Seconddigit1 = uilabel(app.UIFigure);
            app.Seconddigit1.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = ':';
            app.Minutedigit1 = uilabel(app.UIFigure);
            app.Minutedigit1.Text = '0';
            app.Seconddigit0 = uilabel(app.UIFigure);
            app.Seconddigit0.Text = '0';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = ':';
            app.Millisecdigit1 = uilabel(app.UIFigure);
            app.Millisecdigit1.Text = '0';
            app.Millisecdigit0 = uilabel(app.UIFigure);
            app.Millisecdigit0.Text = '0';
            app.HzSlider_2 = uislider(app.UIFigure);
            app.HzSlider_2.Limits = [-15 15];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @HzSlider_2ValueChanged, true);
            app.HzSlider_2.ValueChangingFcn = createCallbackFcn(app, @HzSlider_2ValueChanging, true);
            app.HzSlider_2Label = uilabel(app.UIFigure);
            app.HzSlider_2Label.Text = '63 Hz';
            app.HzSlider_3 = uislider(app.UIFigure);
            app.HzSlider_3.Limits = [-15 15];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @HzSlider_3ValueChanged, true);
            app.HzSlider_3.ValueChangingFcn = createCallbackFcn(app, @HzSlider_3ValueChanging, true);
            app.HzSlider_3Label = uilabel(app.UIFigure);
            app.HzSlider_3Label.Text = '125 Hz';
            app.HzSlider_4 = uislider(app.UIFigure);
            app.HzSlider_4.Limits = [-15 15];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @HzSlider_4ValueChanged, true);
            app.HzSlider_4.ValueChangingFcn = createCallbackFcn(app, @HzSlider_4ValueChanging, true);
            app.HzSlider_4Label = uilabel(app.UIFigure);
            app.HzSlider_4Label.Text = '250 Hz';
            app.HzSlider_5 = uislider(app.UIFigure);
            app.HzSlider_5.Limits = [-15 15];
            app.HzSlider_5.ValueChangedFcn = createCallbackFcn(app, @HzSlider_5ValueChanged, true);
            app.HzSlider_5.ValueChangingFcn = createCallbackFcn(app, @HzSlider_5ValueChanging, true);
            app.HzSlider_5Label = uilabel(app.UIFigure);
            app.HzSlider_5Label.Text = '500 Hz';
            app.kHzSlider = uislider(app.UIFigure);
            app.kHzSlider.Limits = [-15 15];
            app.kHzSlider.ValueChangedFcn = createCallbackFcn(app, @kHzSliderValueChanged, true);
            app.kHzSlider.ValueChangingFcn = createCallbackFcn(app, @kHzSliderValueChanging, true);
            app.kHzSliderLabel = uilabel(app.UIFigure);
            app.kHzSliderLabel.Text = '1 kHz';
            app.kHzSlider_2 = uislider(app.UIFigure);
            app.kHzSlider_2.Limits = [-15 15];
            app.kHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_2ValueChanged, true);
            app.kHzSlider_2.ValueChangingFcn = createCallbackFcn(app, @kHzSlider_2ValueChanging, true);
            app.kHzSlider_2Label = uilabel(app.UIFigure);
            app.kHzSlider_2Label.Text = '2 kHz';
            app.kHzSlider_3 = uislider(app.UIFigure);
            app.kHzSlider_3.Limits = [-15 15];
            app.kHzSlider_3.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_3ValueChanged, true);
            app.kHzSlider_3.ValueChangingFcn = createCallbackFcn(app, @kHzSlider_3ValueChanging, true);
            app.kHzSlider_3Label = uilabel(app.UIFigure);
            app.kHzSlider_3Label.Text = '4 kHz';
            app.kHzSlider_4 = uislider(app.UIFigure);
            app.kHzSlider_4.Limits = [-15 15];
            app.kHzSlider_4.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_4ValueChanged, true);
            app.kHzSlider_4.ValueChangingFcn = createCallbackFcn(app, @kHzSlider_4ValueChanging, true);
            app.kHzSlider_4Label = uilabel(app.UIFigure);
            app.kHzSlider_4Label.Text = '8 kHz';
            app.kHzSlider_5 = uislider(app.UIFigure);
            app.kHzSlider_5.Limits = [-15 15];
            app.kHzSlider_5.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_5ValueChanged, true);
            app.kHzSlider_5.ValueChangingFcn = createCallbackFcn(app, @kHzSlider_5ValueChanging, true);
            app.kHzSlider_5Label = uilabel(app.UIFigure);
            app.kHzSlider_5Label.Text = '16 kHz';
            app.test = uilabel(app.UIFigure);
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end